classdef AnalizaKrvnogSuda < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ANALIZADEFORMACIJEKRVNOGSUDALabel  matlab.ui.control.Label
        KontrolePanel               matlab.ui.container.Panel
        StatusLabel                 matlab.ui.control.Label
        SacuvajRezultateButton      matlab.ui.control.Button
        UcitajRezultateButton       matlab.ui.control.Button
        PokreniObraduButton         matlab.ui.control.Button
        IzaberiROIButton            matlab.ui.control.Button
        KrajnjiFrejmEditField       matlab.ui.control.NumericEditField
        KrajnjifrejmEditFieldLabel  matlab.ui.control.Label
        PocetniFrejmEditField       matlab.ui.control.NumericEditField
        PoetnifrejmLabel            matlab.ui.control.Label
        FrejmSlider                 matlab.ui.control.Slider
        FrejmSliderLabel            matlab.ui.control.Label
        ReprodukujButton            matlab.ui.control.Button
        UcitajVideoButton           matlab.ui.control.Button
        GrafikonAxes                matlab.ui.control.UIAxes
        VideoAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Učitani video-snimak
        VideoObj
        PutanjaVidea
        NazivVidea
    
        % Podaci o video-snimku
        FrameRate
        BrojFrejmova
        TrajanjeVidea
        TrenutniFrejm
    
        % Region od interesa
        ROIPozicija
    
        % Rezultati obrade
        RezultatiObrade
    
        % Putanje projekta
        FolderProjekta
        FolderVideo
        FolderRezultati
        FolderSkripte
    
        % Timer za reprodukciju
        TimerReprodukcije
    end
    
    methods (Access = private)
        
        
        function prikaziFrejm(app, brojFrejma)

            % Provera da li je video učitan
            if isempty(app.VideoObj) || app.BrojFrejmova < 1
                return;
            end
        
            % Frejm mora biti ceo broj unutar dozvoljenog opsega
            brojFrejma = round(brojFrejma);
        
            brojFrejma = max( ...
                1, ...
                min(brojFrejma, app.BrojFrejmova));
        
            % Pretvaranje broja frejma u vreme video-snimka
            vremeFrejma = ...
                (brojFrejma - 1) / app.FrameRate;
        
            % Sprečava postavljanje CurrentTime tačno na kraj videa
            maksimalnoVreme = max( ...
                0, ...
                app.TrajanjeVidea - 1 / app.FrameRate);
        
            app.VideoObj.CurrentTime = min( ...
                vremeFrejma, ...
                maksimalnoVreme);
        
            % Učitavanje frejma
            if hasFrame(app.VideoObj)
        
                frejm = readFrame(app.VideoObj);
        
                imshow( ...
                    frejm, ...
                    'Parent', app.VideoAxes);
        
                title( ...
                    app.VideoAxes, ...
                    sprintf( ...
                        'Frejm %d od %d | vreme: %.2f s', ...
                        brojFrejma, ...
                        app.BrojFrejmova, ...
                        vremeFrejma));
        
                app.VideoAxes.XTick = [];
                app.VideoAxes.YTick = [];
        
                app.TrenutniFrejm = brojFrejma;
                app.FrejmSlider.Value = brojFrejma;
            end
        end
        
        
        function reprodukujSledeciFrejm(app)

            % Provera da li aplikacija i video još postoje
            if isempty(app.VideoObj) || ...
                    ~isvalid(app.UIFigure)
        
                zaustaviReprodukciju(app);
                return;
            end
        
            krajnjiFrejm = round( ...
                app.KrajnjiFrejmEditField.Value);
        
            % Zaustavljanje reprodukcije na kraju izabranog opsega
            if app.TrenutniFrejm >= krajnjiFrejm
        
                zaustaviReprodukciju(app);
        
                app.StatusLabel.Text = sprintf( ...
                    'Status:\nReprodukcija je završena.');
        
                return;
            end
        
            sledeciFrejm = app.TrenutniFrejm + 1;
        
            prikaziFrejm(app, sledeciFrejm);
        
        end
        
        function frejmGray = ucitajFrejmGray(app, brojFrejma)

            if isempty(app.VideoObj)
                error('Video-snimak nije učitan.');
            end
        
            brojFrejma = round(brojFrejma);
        
            brojFrejma = max( ...
                1, ...
                min(brojFrejma, app.BrojFrejmova));
        
            vremeFrejma = ...
                (brojFrejma - 1) / app.FrameRate;
        
            maksimalnoVreme = max( ...
                0, ...
                app.TrajanjeVidea - 1 / app.FrameRate);
        
            app.VideoObj.CurrentTime = min( ...
                vremeFrejma, ...
                maksimalnoVreme);
        
            if ~hasFrame(app.VideoObj)
                error('Nije moguće učitati frejm broj %d.', brojFrejma);
            end
        
            frejm = readFrame(app.VideoObj);
        
            frejmGray = im2gray(frejm);
        
        end
        
        function zaustaviReprodukciju(app)

            % Ako timer postoji i još je važeći
            if ~isempty(app.TimerReprodukcije) && ...
                    isvalid(app.TimerReprodukcije)
        
                % Zaustavljanje timera ako reprodukcija trenutno traje
                if strcmp(app.TimerReprodukcije.Running, 'on')
                    stop(app.TimerReprodukcije);
                end
        
                % Brisanje starog timera
                delete(app.TimerReprodukcije);
            end
        
            app.TimerReprodukcije = [];
        
            % Vraćanje teksta dugmeta
            app.ReprodukujButton.Text = 'Reprodukuj';
        
        end
        
        function prikaziRezultate(app)
        
            if isempty(app.RezultatiObrade)
                return;
            end
        
            rezultati = app.RezultatiObrade;
        
            potrebnaPolja = { ...
                'vremeRelativno', ...
                'precnikVertikalniMedijana', ...
                'precnikNormalaMedijana'};
        
            if ~all(isfield(rezultati, potrebnaPolja))
                error('Učitani rezultati ne sadrže sve potrebne podatke.');
            end
        
            cla(app.GrafikonAxes);
        
            plot( ...
                app.GrafikonAxes, ...
                rezultati.vremeRelativno, ...
                rezultati.precnikVertikalniMedijana, ...
                'LineWidth', 1.2);
        
            hold(app.GrafikonAxes, 'on');
        
            plot( ...
                app.GrafikonAxes, ...
                rezultati.vremeRelativno, ...
                rezultati.precnikNormalaMedijana, ...
                'LineWidth', 1.2);
        
            hold(app.GrafikonAxes, 'off');
        
            grid(app.GrafikonAxes, 'on');
        
            xlabel(app.GrafikonAxes, 'Vreme [s]');
            ylabel(app.GrafikonAxes, 'Prečnik [px]');
        
            title( ...
                app.GrafikonAxes, ...
                'Promena prečnika krvnog suda u vremenu');
        
            legend( ...
                app.GrafikonAxes, ...
                'Vertikalni prečnik', ...
                'Prečnik duž normale', ...
                'Location', 'best');
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Putanje projekta

            folderAplikacije = fileparts(mfilename('fullpath'));
        
            app.FolderProjekta = fileparts(folderAplikacije);
        
            app.FolderVideo = fullfile( ...
                app.FolderProjekta, ...
                'Video');
        
            app.FolderRezultati = fullfile( ...
                app.FolderProjekta, ...
                'Rezultati');
        
            app.FolderSkripte = fullfile( ...
                app.FolderProjekta, ...
                'Skripte');
        
            %% Dodavanje algoritamskih funkcija na MATLAB putanju
        
            if isfolder(app.FolderSkripte)
                addpath(app.FolderSkripte);
            else
                uialert( ...
                    app.UIFigure, ...
                    'Folder Skripte nije pronađen.', ...
                    'Greška putanje');
            end
        
            %% Pravljenje foldera Rezultati ako ne postoji
        
            if ~isfolder(app.FolderRezultati)
                mkdir(app.FolderRezultati);
            end
        
            %% Početne vrednosti promenljivih
        
            app.VideoObj = [];
            app.PutanjaVidea = '';
            app.NazivVidea = '';
        
            app.FrameRate = NaN;
            app.BrojFrejmova = 0;
            app.TrajanjeVidea = 0;
            app.TrenutniFrejm = 1;
        
            app.ROIPozicija = [];
            app.RezultatiObrade = [];
            app.TimerReprodukcije = [];
        
            %% Početno stanje komponenti
        
            app.ReprodukujButton.Enable = 'off';
            app.FrejmSlider.Enable = 'off';
        
            app.PocetniFrejmEditField.Enable = 'off';
            app.KrajnjiFrejmEditField.Enable = 'off';
        
            app.IzaberiROIButton.Enable = 'off';
            app.PokreniObraduButton.Enable = 'off';
            app.SacuvajRezultateButton.Enable = 'off';
        
            app.UcitajVideoButton.Enable = 'on';
            app.UcitajRezultateButton.Enable = 'on';
        
            %% Početne vrednosti polja
        
            app.FrejmSlider.Limits = [1 2];
            app.FrejmSlider.Value = 1;
        
            app.PocetniFrejmEditField.Value = 1;
            app.KrajnjiFrejmEditField.Value = 1;
        
            %% Početna statusna poruka
        
            app.StatusLabel.Text = sprintf( ...
                'Status:\nUčitajte video-snimak.');
        
            %% Priprema ose za video
        
            cla(app.VideoAxes);
        
            title( ...
                app.VideoAxes, ...
                'Video-snimak i detektovane ivice');
        
            app.VideoAxes.XTick = [];
            app.VideoAxes.YTick = [];
        
            %% Priprema ose za rezultate
        
            cla(app.GrafikonAxes);
        
            title( ...
                app.GrafikonAxes, ...
                'Prečnik krvnog suda u vremenu');
        
            xlabel(app.GrafikonAxes, 'Vreme [s]');
            ylabel(app.GrafikonAxes, 'Prečnik [px]');
        
            grid(app.GrafikonAxes, 'on');
        end

        % Button pushed function: UcitajVideoButton
        function UcitajVideoButtonPushed(app, event)
            try

            %% Izbor video-fajla
    
            [nazivFajla, putanjaFajla] = uigetfile( ...
                {'*.mp4;*.avi;*.mov', ...
                 'Video fajlovi (*.mp4, *.avi, *.mov)'}, ...
                'Izaberite video-snimak krvnog suda', ...
                app.FolderVideo);
    
            % Korisnik je zatvorio prozor bez izbora fajla
            if isequal(nazivFajla, 0)
    
                app.StatusLabel.Text = sprintf( ...
                    'Status:\nUčitavanje video-snimka je otkazano.');
    
                return;
            end

            punaPutanja = fullfile( ...
                putanjaFajla, ...
                nazivFajla);
    
            %% Zaustavljanje prethodne reprodukcije, ako postoji
    
            if ~isempty(app.TimerReprodukcije)
    
                if isvalid(app.TimerReprodukcije)
                    stop(app.TimerReprodukcije);
                    delete(app.TimerReprodukcije);
                end
    
                app.TimerReprodukcije = [];
            end
    
            %% Učitavanje video-snimka
    
            noviVideo = VideoReader(punaPutanja);
    
            frameRate = noviVideo.FrameRate;
            trajanjeVidea = noviVideo.Duration;

            brojFrejmova = floor( ...
                frameRate * trajanjeVidea);
    
            if brojFrejmova < 1
                error('Video-snimak ne sadrži validne frejmove.');
            end
    
            %% Čuvanje podataka u aplikaciji
    
            app.VideoObj = noviVideo;
    
            app.PutanjaVidea = punaPutanja;
            app.NazivVidea = nazivFajla;
    
            app.FrameRate = frameRate;
            app.TrajanjeVidea = trajanjeVidea;
            app.BrojFrejmova = brojFrejmova;
    
            app.TrenutniFrejm = 1;
    
            % Novi video poništava prethodni ROI i rezultate
            app.ROIPozicija = [];
            app.RezultatiObrade = [];

            %% Podešavanje slidera

            if brojFrejmova == 1
                app.FrejmSlider.Limits = [1 2];
            else
                app.FrejmSlider.Limits = [1 brojFrejmova];
            end
    
            app.FrejmSlider.Value = 1;
    
            %% Podešavanje polja za frejmove
    
            app.PocetniFrejmEditField.Limits = ...
                [1 brojFrejmova];
    
            app.KrajnjiFrejmEditField.Limits = ...
                [1 brojFrejmova];

            app.PocetniFrejmEditField.Value = 1;
            app.KrajnjiFrejmEditField.Value = brojFrejmova;

            %% Aktiviranje komponenti

            app.ReprodukujButton.Enable = 'on';
            app.FrejmSlider.Enable = 'on';
    
            app.PocetniFrejmEditField.Enable = 'on';
            app.KrajnjiFrejmEditField.Enable = 'on';
    
            app.IzaberiROIButton.Enable = 'on';
    
            % Obrada se uključuje tek nakon izbora ROI regiona
            app.PokreniObraduButton.Enable = 'off';
            app.SacuvajRezultateButton.Enable = 'off';
    
            %% Brisanje prethodnog grafika
    
            cla(app.GrafikonAxes);
    
            title( ...
                app.GrafikonAxes, ...
                'Prečnik krvnog suda u vremenu');
    
            xlabel(app.GrafikonAxes, 'Vreme [s]');
            ylabel(app.GrafikonAxes, 'Prečnik [px]');
    
            grid(app.GrafikonAxes, 'on');
    
            %% Prikaz prvog frejma
    
            prikaziFrejm(app, 1);
    
            %% Status
    
            app.StatusLabel.Text = sprintf( ...
                ['Status:\nVideo je uspešno učitan.\n' ...
                 'Broj frejmova: %d\nFrame rate: %.2f fps'], ...
                brojFrejmova, ...
                frameRate);
    
        catch ME
    
            app.StatusLabel.Text = sprintf( ...
                'Status:\nGreška pri učitavanju video-snimka.');
    
            uialert( ...
                app.UIFigure, ...
                ME.message, ...
                'Greška pri učitavanju');
            end

        end

        % Value changed function: FrejmSlider
        function FrejmSliderValueChanged(app, event)
            if isempty(app.VideoObj)
                return;
            end
        
            brojFrejma = round(app.FrejmSlider.Value);
        
            prikaziFrejm(app, brojFrejma);
        
            app.StatusLabel.Text = sprintf( ...
                'Status:\nPrikazan je frejm %d od %d.', ...
                brojFrejma, ...
                app.BrojFrejmova);

        end

        % Button pushed function: ReprodukujButton
        function ReprodukujButtonPushed(app, event)
                    
            try
        
                if isempty(app.VideoObj)
                    uialert( ...
                        app.UIFigure, ...
                        'Prvo učitajte video-snimak.', ...
                        'Video nije učitan');
        
                    return;
                end
        
                %% Ako reprodukcija već traje, pravi se pauza
        
                if ~isempty(app.TimerReprodukcije) && ...
                        isvalid(app.TimerReprodukcije) && ...
                        strcmp(app.TimerReprodukcije.Running, 'on')
        
                    stop(app.TimerReprodukcije);
        
                    app.ReprodukujButton.Text = 'Reprodukuj';
        
                    app.StatusLabel.Text = sprintf( ...
                        'Status:\nReprodukcija je pauzirana na frejmu %d.', ...
                        app.TrenutniFrejm);
        
                    return;
                end
        
                %% Provera početnog i krajnjeg frejma
        
                pocetniFrejm = round( ...
                    app.PocetniFrejmEditField.Value);
        
                krajnjiFrejm = round( ...
                    app.KrajnjiFrejmEditField.Value);
        
                if pocetniFrejm >= krajnjiFrejm
        
                    uialert( ...
                        app.UIFigure, ...
                        ['Početni frejm mora biti manji ' ...
                         'od krajnjeg frejma.'], ...
                        'Neispravan opseg');
        
                    return;
                end
        
                %% Ako je trenutni frejm van opsega, vraćamo se na početak
        
                if app.TrenutniFrejm < pocetniFrejm || ...
                        app.TrenutniFrejm >= krajnjiFrejm
        
                    prikaziFrejm(app, pocetniFrejm);
                end
        
                %% Brisanje starog timera ako postoji
        
                if ~isempty(app.TimerReprodukcije) && ...
                        isvalid(app.TimerReprodukcije)
        
                    delete(app.TimerReprodukcije);
                end
        
                %% Formiranje novog timera
        
                periodTimera = max( ...
                    1 / app.FrameRate, ...
                    0.03);
        
                app.TimerReprodukcije = timer( ...
                    'ExecutionMode', 'fixedSpacing', ...
                    'Period', periodTimera, ...
                    'BusyMode', 'drop', ...
                    'TimerFcn', @(~, ~) ...
                        reprodukujSledeciFrejm(app));
        
                %% Pokretanje reprodukcije
        
                app.ReprodukujButton.Text = 'Pauziraj';
        
                app.StatusLabel.Text = sprintf( ...
                    'Status:\nReprodukcija video-snimka je pokrenuta.');
        
                start(app.TimerReprodukcije);
        
            catch ME
        
                zaustaviReprodukciju(app);
        
                app.StatusLabel.Text = sprintf( ...
                    'Status:\nGreška tokom reprodukcije.');
        
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Greška reprodukcije');
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            try
                zaustaviReprodukciju(app);
            catch
                % Aplikacija se svakako zatvara.
            end
        
            delete(app);

        end

        % Button pushed function: IzaberiROIButton
        function IzaberiROIButtonPushed(app, event)
            
            try
        
                %% Provera video-snimka
        
                if isempty(app.VideoObj)
        
                    uialert( ...
                        app.UIFigure, ...
                        'Prvo učitajte video-snimak.', ...
                        'Video nije učitan');
        
                    return;
                end
        
                %% Zaustavljanje reprodukcije
        
                zaustaviReprodukciju(app);
        
                %% Čitanje opsega frejmova
        
                pocetniFrejm = round( ...
                    app.PocetniFrejmEditField.Value);
        
                krajnjiFrejm = round( ...
                    app.KrajnjiFrejmEditField.Value);
        
                if pocetniFrejm < 1 || ...
                        krajnjiFrejm > app.BrojFrejmova
        
                    uialert( ...
                        app.UIFigure, ...
                        'Izabrani frejmovi izlaze izvan video-snimka.', ...
                        'Neispravan opseg');
        
                    return;
                end
        
                if pocetniFrejm >= krajnjiFrejm
        
                    uialert( ...
                        app.UIFigure, ...
                        ['Početni frejm mora biti manji ' ...
                         'od krajnjeg frejma.'], ...
                        'Neispravan opseg');
        
                    return;
                end
        
                % Upisujemo zaokružene vrednosti nazad u polja.
                app.PocetniFrejmEditField.Value = pocetniFrejm;
                app.KrajnjiFrejmEditField.Value = krajnjiFrejm;
        
                srednjiFrejm = round( ...
                    (pocetniFrejm + krajnjiFrejm) / 2);
        
                %% Učitavanje karakterističnih frejmova
        
                app.StatusLabel.Text = sprintf( ...
                    'Status:\nFormiranje referentne slike...');
        
                drawnow;
        
                frejmPocetak = ucitajFrejmGray( ...
                    app, ...
                    pocetniFrejm);
        
                frejmSredina = ucitajFrejmGray( ...
                    app, ...
                    srednjiFrejm);
        
                frejmKraj = ucitajFrejmGray( ...
                    app, ...
                    krajnjiFrejm);
        
                %% Formiranje referentne slike
        
                referentnaSlika = uint8( ...
                    (double(frejmPocetak) + ...
                     double(frejmSredina) + ...
                     double(frejmKraj)) / 3);
        
                %% Prikaz referentne slike
        
                imshow( ...
                    referentnaSlika, ...
                    'Parent', app.VideoAxes);
        
                title( ...
                    app.VideoAxes, ...
                    'Označite region krvnog suda');
        
                app.VideoAxes.XTick = [];
                app.VideoAxes.YTick = [];
        
                app.StatusLabel.Text = sprintf( ...
                    ['Status:\nMišem označite region krvnog suda.\n' ...
                     'Dvaput kliknite unutar pravougaonika za potvrdu.']);
        
                %% Ručni izbor ROI oblasti
        
                roiObjekat = drawrectangle( ...
                    app.VideoAxes);
        
                wait(roiObjekat);
        
                % Korisnik je mogao obrisati ROI tokom izbora.
                if ~isvalid(roiObjekat)
        
                    app.StatusLabel.Text = sprintf( ...
                        'Status:\nIzbor ROI oblasti je otkazan.');
        
                    return;
                end
        
                roiPozicija = round( ...
                    roiObjekat.Position);
        
                %% Provera dimenzija ROI oblasti
        
                if roiPozicija(3) < 10 || ...
                        roiPozicija(4) < 10
        
                    delete(roiObjekat);
        
                    uialert( ...
                        app.UIFigure, ...
                        'Izabrani ROI region je premali.', ...
                        'Neispravan ROI');
        
                    return;
                end
        
                %% Ograničavanje ROI oblasti na granice slike
        
                brojRedova = size(referentnaSlika, 1);
                brojKolona = size(referentnaSlika, 2);
        
                roiPozicija(1) = max(1, roiPozicija(1));
                roiPozicija(2) = max(1, roiPozicija(2));
        
                roiPozicija(3) = min( ...
                    roiPozicija(3), ...
                    brojKolona - roiPozicija(1));
        
                roiPozicija(4) = min( ...
                    roiPozicija(4), ...
                    brojRedova - roiPozicija(2));
        
                %% Čuvanje ROI oblasti
        
                app.ROIPozicija = roiPozicija;
        
                % Novi ROI poništava prethodne rezultate.
                app.RezultatiObrade = [];
        
                %% Aktiviranje obrade
        
                app.PokreniObraduButton.Enable = 'on';
                app.SacuvajRezultateButton.Enable = 'off';
        
                %% Status
        
                app.StatusLabel.Text = sprintf( ...
                    ['Status:\nROI region je uspešno izabran.\n' ...
                     'x = %d, y = %d, širina = %d, visina = %d'], ...
                    roiPozicija(1), ...
                    roiPozicija(2), ...
                    roiPozicija(3), ...
                    roiPozicija(4));
        
            catch ME
        
                app.ROIPozicija = [];
                app.PokreniObraduButton.Enable = 'off';
        
                app.StatusLabel.Text = sprintf( ...
                    'Status:\nGreška prilikom izbora ROI oblasti.');
        
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Greška izbora ROI');
            end

        end

        % Button pushed function: PokreniObraduButton
        function PokreniObraduButtonPushed(app, event)
                    
            try
        
                %% 1. Provera potrebnih podataka
        
                if isempty(app.VideoObj) || isempty(app.PutanjaVidea)
        
                    uialert( ...
                        app.UIFigure, ...
                        'Prvo učitajte video-snimak.', ...
                        'Video nije učitan');
        
                    return;
                end
        
                if isempty(app.ROIPozicija)
        
                    uialert( ...
                        app.UIFigure, ...
                        'Prvo izaberite ROI region.', ...
                        'ROI nije izabran');
        
                    return;
                end
        
                %% 2. Zaustavljanje reprodukcije
        
                app.zaustaviReprodukciju();
        
                %% 3. Čitanje početnog i krajnjeg frejma
        
                pocetniFrejm = round( ...
                    app.PocetniFrejmEditField.Value);
        
                krajnjiFrejm = round( ...
                    app.KrajnjiFrejmEditField.Value);
        
                if pocetniFrejm < 1 || ...
                        krajnjiFrejm > app.BrojFrejmova
        
                    uialert( ...
                        app.UIFigure, ...
                        'Izabrani opseg izlazi izvan video-snimka.', ...
                        'Neispravan opseg');
        
                    return;
                end
        
                if pocetniFrejm >= krajnjiFrejm
        
                    uialert( ...
                        app.UIFigure, ...
                        ['Početni frejm mora biti manji ' ...
                         'od krajnjeg frejma.'], ...
                        'Neispravan opseg');
        
                    return;
                end
        
                app.PocetniFrejmEditField.Value = pocetniFrejm;
                app.KrajnjiFrejmEditField.Value = krajnjiFrejm;
        
                %% 4. Privremeno isključivanje kontrola
        
                app.PokreniObraduButton.Enable = 'off';
                app.UcitajVideoButton.Enable = 'off';
                app.ReprodukujButton.Enable = 'off';
                app.FrejmSlider.Enable = 'off';
                app.IzaberiROIButton.Enable = 'off';
        
                app.PocetniFrejmEditField.Enable = 'off';
                app.KrajnjiFrejmEditField.Enable = 'off';
        
                app.StatusLabel.Text = sprintf( ...
                    ['Status:\nObrada video-snimka je pokrenuta.\n' ...
                     'Sačekajte završetak obrade...']);
        
                drawnow;
        
                %% 5. Pokretanje postojeće algoritamske funkcije
        
                udeoMargine = 0.22;
        
                app.RezultatiObrade = ...
                    obradi_video_krvnog_suda( ...
                        app.PutanjaVidea, ...
                        app.ROIPozicija, ...
                        pocetniFrejm, ...
                        krajnjiFrejm, ...
                        udeoMargine);
        
                %% 6. Prikaz rezultata na donjoj osi
        
                app.prikaziRezultate();
                
                %% 7. Statistika uspešnosti
        
                ukupanBroj = ...
                    app.RezultatiObrade.brojObradjenihFrejmova;
        
                brojUspesnihVertikalno = nnz( ...
                    app.RezultatiObrade.uspesnaDetekcija);
        
                brojUspesnihNormala = nnz( ...
                    app.RezultatiObrade.uspesnaDetekcijaNormala);
        
                procenatVertikalno = ...
                    100 * brojUspesnihVertikalno / ukupanBroj;
        
                procenatNormala = ...
                    100 * brojUspesnihNormala / ukupanBroj;
        
                %% 8. Ponovno uključivanje komponenti
        
                app.UcitajVideoButton.Enable = 'on';
                app.ReprodukujButton.Enable = 'on';
                app.FrejmSlider.Enable = 'on';
        
                app.PocetniFrejmEditField.Enable = 'on';
                app.KrajnjiFrejmEditField.Enable = 'on';
        
                app.IzaberiROIButton.Enable = 'on';
                app.PokreniObraduButton.Enable = 'on';
                app.SacuvajRezultateButton.Enable = 'on';
        
                %% 9. Završna statusna poruka
        
                app.StatusLabel.Text = sprintf( ...
                    ['Status:\nObrada je uspešno završena.\n' ...
                     'Obrađeno frejmova: %d\n' ...
                     'Vertikalna detekcija: %.2f %%\n' ...
                     'Normala: %.2f %%'], ...
                    ukupanBroj, ...
                    procenatVertikalno, ...
                    procenatNormala);
        
            catch ME
        
                %% Ponovno uključivanje komponenti nakon greške
        
                app.UcitajVideoButton.Enable = 'on';
        
                if ~isempty(app.VideoObj)
        
                    app.ReprodukujButton.Enable = 'on';
                    app.FrejmSlider.Enable = 'on';
        
                    app.PocetniFrejmEditField.Enable = 'on';
                    app.KrajnjiFrejmEditField.Enable = 'on';
        
                    app.IzaberiROIButton.Enable = 'on';
                end
        
                if ~isempty(app.ROIPozicija)
                    app.PokreniObraduButton.Enable = 'on';
                end
        
                app.StatusLabel.Text = sprintf( ...
                    'Status:\nGreška tokom obrade video-snimka.');
        
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Greška obrade');
            end

        end

        % Button pushed function: SacuvajRezultateButton
        function SacuvajRezultateButtonPushed(app, event)
                    
            try
        
                if isempty(app.RezultatiObrade)
        
                    uialert( ...
                        app.UIFigure, ...
                        'Nema rezultata koje je moguće sačuvati.', ...
                        'Rezultati ne postoje');
        
                    return;
                end
        
                predlozeniNaziv = sprintf( ...
                    'rezultati_frejmovi_%d_%d.mat', ...
                    app.RezultatiObrade.pocetniFrejm, ...
                    app.RezultatiObrade.krajnjiFrejm);
        
                [nazivFajla, putanjaFajla] = uiputfile( ...
                    {'*.mat', 'MATLAB rezultati (*.mat)'}, ...
                    'Sačuvajte rezultate obrade', ...
                    fullfile( ...
                        app.FolderRezultati, ...
                        predlozeniNaziv));
        
                if isequal(nazivFajla, 0)
        
                    app.StatusLabel.Text = sprintf( ...
                        'Status:\nČuvanje rezultata je otkazano.');
        
                    return;
                end
        
                punaPutanjaRezultata = fullfile( ...
                    putanjaFajla, ...
                    nazivFajla);
        
                % Lokalna promenljiva koju upisujemo u MAT fajl.
                rezultatiObrade = app.RezultatiObrade;
        
                save( ...
                    punaPutanjaRezultata, ...
                    'rezultatiObrade', ...
                    '-v7.3');
        
                app.StatusLabel.Text = sprintf( ...
                    ['Status:\nRezultati su uspešno sačuvani.\n' ...
                     'Fajl: %s'], ...
                    nazivFajla);
        
            catch ME
        
                app.StatusLabel.Text = sprintf( ...
                    'Status:\nGreška prilikom čuvanja rezultata.');
        
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Greška čuvanja');
            end

        end

        % Button pushed function: UcitajRezultateButton
        function UcitajRezultateButtonPushed(app, event)
        
            try
        
                %% Izbor MAT fajla
        
                [nazivFajla, putanjaFajla] = uigetfile( ...
                    {'*.mat', 'MATLAB rezultati (*.mat)'}, ...
                    'Izaberite rezultate obrade', ...
                    app.FolderRezultati);
        
                if isequal(nazivFajla, 0)
        
                    app.StatusLabel.Text = sprintf( ...
                        'Status:\nUčitavanje rezultata je otkazano.');
        
                    return;
                end
        
                punaPutanjaRezultata = fullfile( ...
                    putanjaFajla, ...
                    nazivFajla);
        
                %% Učitavanje sadržaja
        
                ucitaniPodaci = load(punaPutanjaRezultata);
        
                if ~isfield(ucitaniPodaci, 'rezultatiObrade')
        
                    error([ ...
                        'Izabrani MAT fajl ne sadrži promenljivu ' ...
                        '"rezultatiObrade".']);
                end
        
                rezultati = ucitaniPodaci.rezultatiObrade;
        
                %% Provera potrebnih polja
        
                potrebnaPolja = { ...
                    'vremeRelativno', ...
                    'precnikVertikalniMedijana', ...
                    'precnikNormalaMedijana', ...
                    'brojObradjenihFrejmova'};
        
                if ~all(isfield(rezultati, potrebnaPolja))
                    error('Izabrani fajl nema očekivanu strukturu rezultata.');
                end
        
                app.RezultatiObrade = rezultati;
        
                if isfield(rezultati, 'roiPozicija')
                    app.ROIPozicija = rezultati.roiPozicija;
                else
                    app.ROIPozicija = [];
                end
        
                %% Prikaz grafikona
        
                app.prikaziRezultate();
        
                app.SacuvajRezultateButton.Enable = 'on';
        
                %% Pokušaj ponovnog učitavanja originalnog videa
        
                videoDostupan = false;
        
                if isfield(rezultati, 'putanjaVidea') && ...
                        isfile(rezultati.putanjaVidea)
        
                    app.VideoObj = VideoReader( ...
                        rezultati.putanjaVidea);
        
                    app.PutanjaVidea = rezultati.putanjaVidea;
        
                    [~, nazivBezEkstenzije, ekstenzija] = ...
                        fileparts(app.PutanjaVidea);
        
                    app.NazivVidea = ...
                        [nazivBezEkstenzije, ekstenzija];
        
                    app.FrameRate = app.VideoObj.FrameRate;
                    app.TrajanjeVidea = app.VideoObj.Duration;
        
                    app.BrojFrejmova = floor( ...
                        app.FrameRate * app.TrajanjeVidea);
        
                    app.TrenutniFrejm = 1;
        
                    videoDostupan = true;
                end
        
                %% Podešavanje komponenti ako je video pronađen
        
                if videoDostupan
        
                    app.FrejmSlider.Limits = ...
                        [1 app.BrojFrejmova];
        
                    app.FrejmSlider.Value = 1;
        
                    app.PocetniFrejmEditField.Limits = ...
                        [1 app.BrojFrejmova];
        
                    app.KrajnjiFrejmEditField.Limits = ...
                        [1 app.BrojFrejmova];
        
                    if isfield(rezultati, 'pocetniFrejm')
                        pocetniFrejm = rezultati.pocetniFrejm;
                    else
                        pocetniFrejm = 1;
                    end
        
                    if isfield(rezultati, 'krajnjiFrejm')
                        krajnjiFrejm = rezultati.krajnjiFrejm;
                    else
                        krajnjiFrejm = app.BrojFrejmova;
                    end
        
                    pocetniFrejm = max( ...
                        1, ...
                        min(round(pocetniFrejm), app.BrojFrejmova));
        
                    krajnjiFrejm = max( ...
                        1, ...
                        min(round(krajnjiFrejm), app.BrojFrejmova));
        
                    if pocetniFrejm >= krajnjiFrejm
                        pocetniFrejm = 1;
                        krajnjiFrejm = app.BrojFrejmova;
                    end
        
                    app.PocetniFrejmEditField.Value = ...
                        pocetniFrejm;
        
                    app.KrajnjiFrejmEditField.Value = ...
                        krajnjiFrejm;
        
                    app.ReprodukujButton.Enable = 'on';
                    app.FrejmSlider.Enable = 'on';
        
                    app.PocetniFrejmEditField.Enable = 'on';
                    app.KrajnjiFrejmEditField.Enable = 'on';
        
                    app.IzaberiROIButton.Enable = 'on';
        
                    if ~isempty(app.ROIPozicija)
                        app.PokreniObraduButton.Enable = 'on';
                    end
        
                    app.prikaziFrejm(pocetniFrejm);
        
                    app.StatusLabel.Text = sprintf( ...
                        ['Status:\nRezultati i video su učitani.\n' ...
                         'Obrađeno frejmova: %d'], ...
                        rezultati.brojObradjenihFrejmova);
        
                else
        
                    % Grafik se može prikazati i bez originalnog videa.
                    app.VideoObj = [];
                    app.PutanjaVidea = '';
                    app.NazivVidea = '';
        
                    app.ReprodukujButton.Enable = 'off';
                    app.FrejmSlider.Enable = 'off';
        
                    app.PocetniFrejmEditField.Enable = 'off';
                    app.KrajnjiFrejmEditField.Enable = 'off';
        
                    app.IzaberiROIButton.Enable = 'off';
                    app.PokreniObraduButton.Enable = 'off';
        
                    app.StatusLabel.Text = sprintf( ...
                        ['Status:\nRezultati su učitani i prikazani.\n' ...
                         'Originalni video nije pronađen.']);
                end
        
            catch ME
        
                app.StatusLabel.Text = sprintf( ...
                    'Status:\nGreška prilikom učitavanja rezultata.');
        
                uialert( ...
                    app.UIFigure, ...
                    ME.message, ...
                    'Greška učitavanja');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VideoAxes = uiaxes(app.UIFigure);
            title(app.VideoAxes, 'Title')
            xlabel(app.VideoAxes, 'X')
            ylabel(app.VideoAxes, 'Y')
            zlabel(app.VideoAxes, 'Z')
            app.GrafikonAxes = uiaxes(app.UIFigure);
            title(app.GrafikonAxes, 'Title')
            xlabel(app.GrafikonAxes, 'X')
            ylabel(app.GrafikonAxes, 'Y')
            zlabel(app.GrafikonAxes, 'Z')
            app.KontrolePanel = uipanel(app.UIFigure);
            app.KontrolePanel.Title = 'Kontrole';
            app.UcitajVideoButton = uibutton(app.KontrolePanel, 'push');
            app.UcitajVideoButton.ButtonPushedFcn = createCallbackFcn(app, @UcitajVideoButtonPushed, true);
            app.UcitajVideoButton.Text = 'Učitaj snimak';
            app.ReprodukujButton = uibutton(app.KontrolePanel, 'push');
            app.ReprodukujButton.ButtonPushedFcn = createCallbackFcn(app, @ReprodukujButtonPushed, true);
            app.ReprodukujButton.Text = 'Reprodukuj';
            app.FrejmSliderLabel = uilabel(app.KontrolePanel);
            app.FrejmSliderLabel.Text = 'Frejm';
            app.FrejmSlider = uislider(app.KontrolePanel);
            app.FrejmSlider.ValueChangedFcn = createCallbackFcn(app, @FrejmSliderValueChanged, true);
            app.PoetnifrejmLabel = uilabel(app.KontrolePanel);
            app.PoetnifrejmLabel.Text = 'Početni frejm:';
            app.PocetniFrejmEditField = uieditfield(app.KontrolePanel, 'numeric');
            app.KrajnjifrejmEditFieldLabel = uilabel(app.KontrolePanel);
            app.KrajnjifrejmEditFieldLabel.Text = 'Krajnji frejm:';
            app.KrajnjiFrejmEditField = uieditfield(app.KontrolePanel, 'numeric');
            app.IzaberiROIButton = uibutton(app.KontrolePanel, 'push');
            app.IzaberiROIButton.ButtonPushedFcn = createCallbackFcn(app, @IzaberiROIButtonPushed, true);
            app.IzaberiROIButton.Text = 'Izaberi ROI';
            app.PokreniObraduButton = uibutton(app.KontrolePanel, 'push');
            app.PokreniObraduButton.ButtonPushedFcn = createCallbackFcn(app, @PokreniObraduButtonPushed, true);
            app.PokreniObraduButton.Text = 'Pokreni obradu';
            app.UcitajRezultateButton = uibutton(app.KontrolePanel, 'push');
            app.UcitajRezultateButton.ButtonPushedFcn = createCallbackFcn(app, @UcitajRezultateButtonPushed, true);
            app.UcitajRezultateButton.Text = 'Učitaj rezultate';
            app.SacuvajRezultateButton = uibutton(app.KontrolePanel, 'push');
            app.SacuvajRezultateButton.ButtonPushedFcn = createCallbackFcn(app, @SacuvajRezultateButtonPushed, true);
            app.SacuvajRezultateButton.Text = 'Sačuvaj rezultate';
            app.StatusLabel = uilabel(app.KontrolePanel);
            app.StatusLabel.Text = {'Status:'; ''; 'Učitajte video snimak.'};
            app.ANALIZADEFORMACIJEKRVNOGSUDALabel = uilabel(app.UIFigure);
            app.ANALIZADEFORMACIJEKRVNOGSUDALabel.Text = 'ANALIZA DEFORMACIJE KRVNOG SUDA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalizaKrvnogSuda

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end